classdef VisualizacionDatos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DiagramadeDispersionTab       matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        Panel_3                       matlab.ui.container.Panel
        AnchodelPtaloCheckBox         matlab.ui.control.CheckBox
        LongituddelPtaloCheckBox      matlab.ui.control.CheckBox
        AnchodelSpaloCheckBox         matlab.ui.control.CheckBox
        LongituddelSpaloCheckBox      matlab.ui.control.CheckBox
        DiagramaDispersionUIAxes      matlab.ui.control.UIAxes
        MedidasdeTendenciaCentralTab  matlab.ui.container.Tab
        Panel_5                       matlab.ui.container.Panel
        CategoraDropDown              matlab.ui.control.DropDown
        CategoraDropDownLabel         matlab.ui.control.Label
        Panel_4                       matlab.ui.container.Panel
        MedidasUITable                matlab.ui.control.Table
        HistogramaTab                 matlab.ui.container.Tab
        IntervalosSlider              matlab.ui.control.Slider
        IntervalosSliderLabel         matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        LongituddelPtaloButton        matlab.ui.control.Button
        AnchodelPtaloButton           matlab.ui.control.Button
        LongituddelSpaloButton        matlab.ui.control.Button
        AnchodelSpaloButton           matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        HistogramaUIAxes              matlab.ui.control.UIAxes
        DiagramadeCajayBigotesTab     matlab.ui.container.Tab
        CajaYBigotesUIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        listaSeleccionados = {};

        table

        seleccionadoHistograma;

        %A ordered variety and converted to categorical:
        sepal_length = [1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, ...
            2, 3, 1, 1, 1, 1, 1, 1, 1, 1, 2, 2, 1, 1, 1, 1, 1, 1, 1, 1, ...
            2, 1, 1, 1, 2, 1, 1, 1, 1, 1, 1, 2, 1, 1, 2, 2, 2, 2, 2, 2, ...
            2, 2, 2, 3, 1, 1, 2, 2, 2, 2, 2, 3, 1, 2, 2, 3, 3, 3, 2, 2, ...
            3, 2, 2, 2, 3, 3, 2, 2, 2, 3, 3, 2, 2, 3, 3, 2, 2, 2, 3, 3, ...
            3, 3, 3, 2, 2, 3, 3, 3, 3, 2, 3, 3, 3, 3, 2, 2, 2, 2, 3, 3, ...
            3, 3, 2, 3, 3, 2, 3, 3, 3, 2, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3]; 

        %B ordered variety and converted to categorical:
        sepal_width = [2, 2, 2, 3, 1, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 3, 3, 3, 3, 2, ...
            2, 2, 3, 3, 2, 2, 2, 2, 2, 3, 3, 3, 3, 2, 2, 2, 2, 2, 2, 3, ...
            3, 3, 3, 3, 3, 3, 3, 1, 2, 2, 2, 2, 2, 2, 2, 3, 3, 1, 1, 1, ...
            1, 1, 1, 2, 2, 2, 2, 2, 2, 2, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, ...
            3, 3, 1, 1, 1, 1, 2, 2, 2, 3, 3, 3, 3, 1, 1, 1, 1, 1, 2, 2, ...
            2, 3, 3, 3, 3, 3, 1, 1, 2, 3, 3, 3, 1, 1, 1, 1, 1, 1, 1, 1, ...
            1, 2, 3, 3, 1, 1, 1, 1, 1, 1, 1, 1, 1, 3, 1, 1, 1, 1, 1, 1, ...
            1, 3, 3, 1, 1, 1, 1, 1, 1];

        %C ordered variety and converted to categorical:
        petal_length = [1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, ...
            1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, ...
            1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, ...
            2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 3, 2, 2, ...
            2, 2, 2, 2, 2, 2, 2, 3, 3, 2, 3, 3, 3, 2, 3, 3, 3, 2, 3, 3, ...
            3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, ...
            3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, ...
            3, 3, 3, 3, 3, 3, 3, 3, 3, 3];
        
        %D ordered variety and converted to categorical:
        petal_width = [1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, ...
            1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 1, ...
            1, 1, 1, 1, 1, 1, 1, 1, 1, 1, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, ...
            2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 2, 3, 2, 2, ...
            2, 2, 2, 2, 2, 2, 2, 3, 3, 2, 2, 3, 2, 3, 2, 3, 3, 3, 3, 3, ...
            3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, ...
            3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, 3, ...
            3, 3, 3, 3, 3, 3, 3, 3, 3, 3];

        especies categorical

    end
    
    methods (Access = private)
        
        function checkBoxDispersion(app, variable, value)
            if value
                app.listaSeleccionados{end + 1} = variable;
            else
                indiceEliminar = find(strcmp(app.listaSeleccionados, variable));
                app.listaSeleccionados(indiceEliminar) = []; 
            end

            if numel(app.listaSeleccionados) == 2
                app.table = readtable("iris.xlsx","Sheet",app.listaSeleccionados{1});

                x = table2array(app.table(:,app.listaSeleccionados{1}));
                xEspecies = categorical(table2array(app.table(:,'variety')));

                app.table = readtable("iris.xlsx","Sheet",app.listaSeleccionados{2});

                y = table2array(app.table(:,app.listaSeleccionados{2}));
                yEspecies = categorical(table2array(app.table(:,'variety')));

                cla(app.DiagramaDispersionUIAxes);

                hold(app.DiagramaDispersionUIAxes, 'on');

                clases = categories(app.especies);
                colores = [0 0.4470 0.7410; 0.8500 0.3250 0.0980; 0.9290 0.6940 0.1250];
                marcadores = {'o', 's', '^'};

                for k = 1:numel(clases)
                    idx = (xEspecies == clases{k});
                    idy = (yEspecies == clases{k});
                    scatter(app.DiagramaDispersionUIAxes, x(idx), y(idy), 45, ...
                        'MarkerFaceColor', colores(k,:), 'MarkerEdgeColor', 'k', ...
                        'Marker', marcadores{k}, 'DisplayName', char(clases{k}));
                end

                legend(app.DiagramaDispersionUIAxes, 'show', 'Location', 'northeast');
                
                hold(app.DiagramaDispersionUIAxes, 'off');
                
                
                app.DiagramaDispersionUIAxes.XLabel.String = getName(app,app.listaSeleccionados{1});
                app.DiagramaDispersionUIAxes.YLabel.String = getName(app,app.listaSeleccionados{2});
            else
                cla(app.DiagramaDispersionUIAxes);
                app.DiagramaDispersionUIAxes.XLabel.String = "";
                app.DiagramaDispersionUIAxes.YLabel.String = "";
            end
        end

        function nombre = getName(~,variable)
            switch variable
                case 'sepal_width'
                    nombre = "Ancho de Sépalo";
                case 'sepal_length'
                    nombre = "Largo de Sépalo";
                case 'petal_width'
                    nombre = "Ancho de Pétalo";
                case 'petal_length'
                    nombre = "Largo de Pétalo";
            end
        end

        function m = mediaRecortada(~,datos, porcentaje)
                % Asegurar que sea numérico y vector columna
                x = double(datos(:));

                % Descartar posibles valores vacíos o NaN del Excel
                x = x(~isnan(x));

                if isempty(x)
                    m = NaN;
                    return;
                end

                x = sort(x);
                n = numel(x);

                % Número de elementos a recortar de cada extremo (porcentaje / 2 de cada lado)
                k = floor((porcentaje / 100) * n / 2);

                % Si k supera la mitad de los datos, toma la mediana
                if (2 * k >= n)
                    m = median(x);
                else
                    x_recortado = x((1 + k) : (n - k));
                    m = mean(x_recortado);
                end
        end

        function actualizarHistograma(app)
            if isempty(app.seleccionadoHistograma)
                return;
            end

            cla(app.HistogramaUIAxes);
            hold(app.HistogramaUIAxes, 'on');

            numBins = round(app.IntervalosSlider.Value);
            clases = categories(app.especies);
            colores = [0 0.4470 0.7410; 0.8500 0.3250 0.0980; 0.9290 0.6940 0.1250];

            selecEspecies = categorical(table2array(app.table(:,'variety')));

            for k = 1:numel(clases)
                idx = (selecEspecies == clases{k});
                subDatos = app.seleccionadoHistograma(idx);
                histogram(app.HistogramaUIAxes, subDatos, numBins, ...
                    'FaceColor', colores(k,:), 'FaceAlpha', 0.45, ...
                    'EdgeColor', 'none', 'DisplayName', char(clases{k}));
            end

            legend(app.HistogramaUIAxes, 'show', 'Location', 'northeast');
            hold(app.HistogramaUIAxes, 'off');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.table = readtable("iris.xlsx", "Sheet", "iris");
            
            app.especies = categorical(table2array(app.table(:,'especies')))
            
            
            % Nombres de las hojas y de las variables
            hojas = {'sepal_length', 'sepal_width', 'petal_length', 'petal_width'};
            columnas = {'sepal_length', 'sepal_width', 'petal_length', 'petal_width'};
            etiquetas = {'Longitud del Sépalo', 'Ancho del Sépalo', 'Longitud del Pétalo', 'Ancho del Pétalo'};
            
            valoresCell = cell(4, 1);
            especiesCell = cell(4, 1);
            gruposCell = cell(4, 1);
            
            % 1. Leer los datos de cada hoja
            for i = 1:4
                t = readtable("iris.xlsx", "Sheet", hojas{i});
                
                % Extraer valores numéricos (vector columna)
                valoresCell{i} = t.(columnas{i});
                
                % Extraer etiquetas de especie
                especiesCell{i} = cellstr(t.variety);
                
                % Crear etiquetas del eje X para este grupo
                gruposCell{i} = repmat(etiquetas(i), height(t), 1);
            end
            
            % 2. Concatenar todo en vectores verticales simples
            valores = vertcat(valoresCell{:});                          % Vector numérico Nx1
            gruposEspecie = categorical(vertcat(especiesCell{:}));      % Vector categórico Nx1
            grupos = categorical(vertcat(gruposCell{:}), etiquetas);    % Vector categórico Nx1 con orden fijo
            
            % 3. Graficar en el eje
            cla(app.CajaYBigotesUIAxes);
            boxchart(app.CajaYBigotesUIAxes, grupos, valores, 'GroupByColor', gruposEspecie);
            legend(app.CajaYBigotesUIAxes, 'show', 'Location', 'northeast');
        end

        % Value changed function: AnchodelPtaloCheckBox
        function AnchodelPtaloCheckBoxValueChanged(app, event)
            value = app.AnchodelPtaloCheckBox.Value;
            checkBoxDispersion(app,'petal_width',value);
        end

        % Value changed function: AnchodelSpaloCheckBox
        function AnchodelSpaloCheckBoxValueChanged(app, event)
            value = app.AnchodelSpaloCheckBox.Value;
            checkBoxDispersion(app,'sepal_width',value);
        end

        % Value changed function: LongituddelPtaloCheckBox
        function LongituddelPtaloCheckBoxValueChanged(app, event)
            value = app.LongituddelPtaloCheckBox.Value;
            checkBoxDispersion(app,'petal_length',value);
        end

        % Value changed function: LongituddelSpaloCheckBox
        function LongituddelSpaloCheckBoxValueChanged(app, event)
            value = app.LongituddelSpaloCheckBox.Value;
            checkBoxDispersion(app,'sepal_length',value);
        end

        % Button pushed function: AnchodelSpaloButton
        function AnchodelSpaloButtonPushed(app, event)
            app.table = readtable("iris.xlsx","Sheet","sepal_width");
            app.seleccionadoHistograma = table2array(app.table(:,'sepal_width'));
            actualizarHistograma(app);
        end

        % Button pushed function: LongituddelSpaloButton
        function LongituddelSpaloButtonPushed(app, event)
            app.table = readtable("iris.xlsx","Sheet","sepal_length");
            app.seleccionadoHistograma = table2array(app.table(:,'sepal_length'));
            actualizarHistograma(app);
        end

        % Button pushed function: AnchodelPtaloButton
        function AnchodelPtaloButtonPushed(app, event)
            app.table = readtable("iris.xlsx","Sheet","petal_width");
            app.seleccionadoHistograma = table2array(app.table(:,'petal_width'));
            actualizarHistograma(app);
        end

        % Button pushed function: LongituddelPtaloButton
        function LongituddelPtaloButtonPushed(app, event)
            app.table = readtable("iris.xlsx","Sheet","petal_length");
            app.seleccionadoHistograma = table2array(app.table(:,'petal_length'));
            actualizarHistograma(app);
        end

        % Value changed function: IntervalosSlider
        function IntervalosSliderValueChanged(app, event)
            actualizarHistograma(app);
        end

        % Value changed function: CategoraDropDown
        function CategoraDropDownValueChanged(app, event)
           value = app.CategoraDropDown.Value;
    
    if isempty(app.table)
        app.table = readtable("iris.xlsx", "Sheet", "iris");
    end
    
    % Filtrar por la variedad
    if strcmp(value, 'Todas')
        datosFiltrados = app.table;
    else
        colVariedad = string(app.table{:, 5}); % Columna 'variety'
        filas = strcmpi(strtrim(colVariedad), value);
        datosFiltrados = app.table(filas, :);
    end
    
    % Asegurar matriz numérica de tamaño N x 4
    X = double(datosFiltrados{:, 1:4});
    
    % Calcular cada métrica de forma segura y forzar forma fila (1, :)
    calc_media     = mean(X, 1, 'omitnan');
    calc_recortada = mediaRecortada(app, X, 10);
    calc_mediana   = median(X, 1, 'omitnan');
    
    % mode puede devolver empates o estructuras imprevistas; tomamos la primera fila
    calc_moda      = mode(X, 1);
    calc_moda      = calc_moda(1, :); 
    
    calc_var       = var(X, 0, 1, 'omitnan');
    calc_std       = std(X, 0, 1, 'omitnan');
    
    % Prealocar la matriz de 6x4 para que vertcat no pueda fallar jamás
    matrizEstadisticas = zeros(6, 4);
    matrizEstadisticas(1, :) = calc_media(1, :);
    matrizEstadisticas(2, :) = calc_recortada(1, :);
    matrizEstadisticas(3, :) = calc_mediana(1, :);
    matrizEstadisticas(4, :) = calc_moda(1, :);
    matrizEstadisticas(5, :) = calc_var(1, :);
    matrizEstadisticas(6, :) = calc_std(1, :);

    % Asignar directamente a la UITable (que tiene 4 columnas)
    app.MedidasUITable.Data = matrizEstadisticas;
    app.MedidasUITable.RowName = {'Media', 'Media Recortada', 'Mediana', 'Moda', 'Varianza', 'Desv Estándar'};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DiagramadeDispersionTab = uitab(app.TabGroup);
            app.DiagramadeDispersionTab.Title = 'Diagrama de Dispersión';
            app.GridLayout3 = uigridlayout(app.DiagramadeDispersionTab);
            app.DiagramaDispersionUIAxes = uiaxes(app.GridLayout3);
            zlabel(app.DiagramaDispersionUIAxes, 'Z')
            app.Panel_3 = uipanel(app.GridLayout3);
            app.LongituddelSpaloCheckBox = uicheckbox(app.Panel_3);
            app.LongituddelSpaloCheckBox.ValueChangedFcn = createCallbackFcn(app, @LongituddelSpaloCheckBoxValueChanged, true);
            app.LongituddelSpaloCheckBox.Text = 'Longitud del Sépalo';
            app.AnchodelSpaloCheckBox = uicheckbox(app.Panel_3);
            app.AnchodelSpaloCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnchodelSpaloCheckBoxValueChanged, true);
            app.AnchodelSpaloCheckBox.Text = 'Ancho del Sépalo';
            app.LongituddelPtaloCheckBox = uicheckbox(app.Panel_3);
            app.LongituddelPtaloCheckBox.ValueChangedFcn = createCallbackFcn(app, @LongituddelPtaloCheckBoxValueChanged, true);
            app.LongituddelPtaloCheckBox.Text = 'Longitud del Pétalo';
            app.AnchodelPtaloCheckBox = uicheckbox(app.Panel_3);
            app.AnchodelPtaloCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnchodelPtaloCheckBoxValueChanged, true);
            app.AnchodelPtaloCheckBox.Text = 'Ancho del Pétalo';
            app.MedidasdeTendenciaCentralTab = uitab(app.TabGroup);
            app.MedidasdeTendenciaCentralTab.Title = 'Medidas de Tendencia Central ';
            app.Panel_4 = uipanel(app.MedidasdeTendenciaCentralTab);
            app.MedidasUITable = uitable(app.Panel_4);
            app.MedidasUITable.ColumnName = {'Longitud de Sépalo'; 'Ancho de Sépalo'; 'Longitud de Pétalo'; 'Ancho de Pétalo'};
            app.MedidasUITable.RowName = {};
            app.Panel_5 = uipanel(app.MedidasdeTendenciaCentralTab);
            app.CategoraDropDownLabel = uilabel(app.Panel_5);
            app.CategoraDropDownLabel.Text = 'Categoría';
            app.CategoraDropDown = uidropdown(app.Panel_5);
            app.CategoraDropDown.Items = {'Setosa', 'Versicolor', 'Virginica', 'Todas'};
            app.CategoraDropDown.ValueChangedFcn = createCallbackFcn(app, @CategoraDropDownValueChanged, true);
            app.CategoraDropDown.Value = 'Setosa';
            app.HistogramaTab = uitab(app.TabGroup);
            app.HistogramaTab.Title = 'Histograma';
            app.Panel = uipanel(app.HistogramaTab);
            app.HistogramaUIAxes = uiaxes(app.Panel);
            xlabel(app.HistogramaUIAxes, 'Valores')
            ylabel(app.HistogramaUIAxes, 'Frecuencias')
            zlabel(app.HistogramaUIAxes, 'Z')
            app.Panel_2 = uipanel(app.HistogramaTab);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.AnchodelSpaloButton = uibutton(app.GridLayout2, 'push');
            app.AnchodelSpaloButton.ButtonPushedFcn = createCallbackFcn(app, @AnchodelSpaloButtonPushed, true);
            app.AnchodelSpaloButton.Text = 'Ancho del Sépalo';
            app.LongituddelSpaloButton = uibutton(app.GridLayout2, 'push');
            app.LongituddelSpaloButton.ButtonPushedFcn = createCallbackFcn(app, @LongituddelSpaloButtonPushed, true);
            app.LongituddelSpaloButton.Text = 'Longitud del Sépalo';
            app.AnchodelPtaloButton = uibutton(app.GridLayout2, 'push');
            app.AnchodelPtaloButton.ButtonPushedFcn = createCallbackFcn(app, @AnchodelPtaloButtonPushed, true);
            app.AnchodelPtaloButton.Text = 'Ancho del Pétalo';
            app.LongituddelPtaloButton = uibutton(app.GridLayout2, 'push');
            app.LongituddelPtaloButton.ButtonPushedFcn = createCallbackFcn(app, @LongituddelPtaloButtonPushed, true);
            app.LongituddelPtaloButton.Text = 'Longitud del Pétalo';
            app.IntervalosSliderLabel = uilabel(app.HistogramaTab);
            app.IntervalosSliderLabel.Text = 'Intervalos';
            app.IntervalosSlider = uislider(app.HistogramaTab);
            app.IntervalosSlider.Limits = [1 15];
            app.IntervalosSlider.ValueChangedFcn = createCallbackFcn(app, @IntervalosSliderValueChanged, true);
            app.IntervalosSlider.Value = 1;
            app.DiagramadeCajayBigotesTab = uitab(app.TabGroup);
            app.DiagramadeCajayBigotesTab.Title = 'Diagrama de Caja y Bigotes';
            app.CajaYBigotesUIAxes = uiaxes(app.DiagramadeCajayBigotesTab);
            title(app.CajaYBigotesUIAxes, 'Distribución')
            ylabel(app.CajaYBigotesUIAxes, 'Valores')
            zlabel(app.CajaYBigotesUIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizacionDatos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end